classdef palmprintGel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure                % Palmprint Gel
        Label        matlab.ui.control.Label         % Scegli il tipo di an...
        ButtonGroup  matlab.ui.container.ButtonGroup % Tipo di analisi
        RadioButton  matlab.ui.control.RadioButton   % 2D
        RadioButton2 matlab.ui.control.RadioButton   % 3D
        Button       matlab.ui.control.Button        % Avvia analisi
        TabGroup     matlab.ui.container.TabGroup    % DiBello, Restaino, D...
        Tab          matlab.ui.container.Tab         % DiBello
        TextArea8    matlab.ui.control.TextArea      % La strategia estrae ...
        Tab2         matlab.ui.container.Tab         % Restaino
        TextArea3    matlab.ui.control.TextArea      % La procedura di estr...
        Tab3         matlab.ui.container.Tab         % Dionisio
        TextArea4    matlab.ui.control.TextArea      % La strategia è textu...
        Tab4         matlab.ui.container.Tab         % Gugliotta
        TextArea5    matlab.ui.control.TextArea      % La strategia utilizz...
        Tab5         matlab.ui.container.Tab         % Marino
        TextArea6    matlab.ui.control.TextArea      % Questa strategia è b...
        Tab6         matlab.ui.container.Tab         % Micucci
        TextArea7    matlab.ui.control.TextArea      % - Lettura dell'immagine
        Tab7         matlab.ui.container.Tab         % Calia
        TextArea9    matlab.ui.control.TextArea      % Si è stato implement...
        ButtonGroup2 matlab.ui.container.ButtonGroup % Strategie di analisi
        RadioButton3 matlab.ui.control.RadioButton   % Strategia DiBello et...
        RadioButton4 matlab.ui.control.RadioButton   % Strategia Restaino e...
        RadioButton5 matlab.ui.control.RadioButton   % Strategia Dionisio
        RadioButton6 matlab.ui.control.RadioButton   % Strategia Gugliotta
        RadioButton7 matlab.ui.control.RadioButton   % Strategia Marino
        RadioButton8 matlab.ui.control.RadioButton   % Strategia Micucci
        RadioButton9 matlab.ui.control.RadioButton   % Strategia Calia
        Button2      matlab.ui.control.Button        % Calcola statistiche
        Button3      matlab.ui.control.Button        % Visualizza statistiche
        Label2       matlab.ui.control.Label         % Avvia analisi - perm...
        Button4      matlab.ui.control.Button        % Disegna DET
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button button pushed function
        function AvviaAnalisi(app)
            if app.RadioButton2.Value
                analisi3D();
                return;
            end
            if app.RadioButton3.Value
                analisi2D('DiBello');
                return;
            end 
            if app.RadioButton4.Value
                analisi2D('Restaino');
                return;
            end 
            if app.RadioButton5.Value
                analisi2D('Dionisio');
                return;
            end 
            if app.RadioButton6.Value
                analisi2D('Gugliotta');
                return;
            end 
            if app.RadioButton7.Value
                analisi2D('Marino');
                return;
            end
            if app.RadioButton8.Value
                analisi2D('Micucci');
                return;
            end 
            if app.RadioButton9.Value
                warning off;
                registraDatabase
                disp('Matching in corso...');
                identificazioneMatching3D_calia
                disp('Matching effettuato.');
                disp('Estrazione delle statistiche in corso...');
                statics_3D
                disp('Estrazione delle statistiche effettuata');
                
                %disegnamo la DET
                disp('Creazione delle curve DET in corso...');
                drawDETcurve
                disp('Creazione delle curve DET effettuata.');
                return;
            end
        end

        % ButtonGroup selection change function
        function AnalysisTypeRadioChangeSelection(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text,'3D')
                app.RadioButton3.Enable = 'off';
                app.RadioButton4.Enable = 'off';
                app.RadioButton5.Enable = 'off';
                app.RadioButton6.Enable = 'off';
                app.RadioButton7.Enable = 'off';
                app.RadioButton8.Enable = 'off';
            else
                app.RadioButton3.Enable = 'on';
                app.RadioButton4.Enable = 'on';
                app.RadioButton5.Enable = 'on';
                app.RadioButton6.Enable = 'on';
                app.RadioButton7.Enable = 'on';
                app.RadioButton8.Enable = 'on';
            end
        end

        % ButtonGroup2 selection change function
        function RadioSelectionChanged(app, event)
            %cambio radioButton cambia il tab
            selectedButton = app.ButtonGroup2.SelectedObject;
            if strcmp(selectedButton.Text, 'Strategia DiBello')
                app.TabGroup.SelectedTab = app.Tab;
                return;
            end
            if strcmp(selectedButton.Text, 'Strategia Restaino')
                app.TabGroup.SelectedTab = app.Tab2;
                return;
            end
            if strcmp(selectedButton.Text, 'Strategia Dionisio')
                app.TabGroup.SelectedTab = app.Tab3;
                return;
            end
            if strcmp(selectedButton.Text, 'Strategia Gugliotta')
                app.TabGroup.SelectedTab = app.Tab4;
                return;
            end
            if strcmp(selectedButton.Text, 'Strategia Marino')
                app.TabGroup.SelectedTab = app.Tab5;
                return;
            end
            if strcmp(selectedButton.Text, 'Strategia Micucci')
                app.TabGroup.SelectedTab = app.Tab6;
                return;
            end
            if strcmp(selectedButton.Text, 'Strategia Calia')
                app.TabGroup.SelectedTab = app.Tab7;
                return;
            end
        end

        % Button2 button pushed function
        function BottoneCalcolaStatistiche(app)
            %calcolo delle statistiche
            warning off;
            close all;
            disp('Estrazione delle statistiche in corso...');
            statics_3D
            disp('Estrazione delle statistiche effettuata');
            
            %disegnamo la DET
            disp('Creazione delle curve DET in corso...');
            drawDETcurve
            disp('Creazione delle curve DET effettuata.');
        end

        % Button3 button pushed function
        function BottoneVisualizzaStatistiche(app)
            visualizzaStatistiche
        end

        % TabGroup selection changed function
        function TabGroupSelectionChanged(app)
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Title, 'DiBello')
                app.RadioButton3.Value = 1;
                return;
            end
            if strcmp(selectedTab.Title, 'Restaino')
                app.RadioButton4.Value = 1;
                return;
            end
            if strcmp(selectedTab.Title, 'Dionisio')
                app.RadioButton5.Value = 1;
                return;
            end
            if strcmp(selectedTab.Title, 'Gugliotta')
                app.RadioButton6.Value = 1;
                return;
            end
            if strcmp(selectedTab.Title, 'Marino')
                app.RadioButton7.Value = 1;
                return;
            end
            if strcmp(selectedTab.Title, 'Micucci')
                app.RadioButton8.Value = 1;
                return;
            end
            if strcmp(selectedTab.Title, 'Calia')
                app.RadioButton9.Value = 1;
                return;
            end
        end

        % Button4 button pushed function
        function ButtonDisegnaDETPushed(app)
            drawAllDet;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'Palmprint Gel';
            setAutoResize(app, app.UIFigure, true)
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Scegli il tipo di analisi da eseguire:';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AnalysisTypeRadioChangeSelection, true);
            app.ButtonGroup.Title = 'Tipo di analisi';
            app.ButtonGroup.Units = 'pixels';
            app.RadioButton = uiradiobutton(app.ButtonGroup);
            app.RadioButton.Text = '2D';
            app.RadioButton.Value = true;
            app.RadioButton2 = uiradiobutton(app.ButtonGroup);
            app.RadioButton2.Enable = 'off';
            app.RadioButton2.Text = '3D';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @AvviaAnalisi);
            app.Button.Text = 'Avvia analisi';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Units = 'pixels';
            app.Tab = uitab(app.TabGroup);
            app.Tab.Units = 'pixels';
            app.Tab.Title = 'DiBello';
            app.TextArea8 = uitextarea(app.Tab);
            app.TextArea8.Editable = 'off';
            app.TextArea8.Value = {'La strategia estrae le linee principali del palmo della mano nelle direzioni a 0°, 45°, 90°, '; '135° e si basa su un approccio ideato dai ricercatori dell’Università di Palermo per immagini in scala di grigi di dimensione 128x128.'; ''; '- Lettura dell''immagine'; '- Aumento del contrasto'; '- Ridimensionamento dell''immagine'; '- Applicazione del filtro di Frost'; '- Normalizzazione'; '- Clonazione dell''immagine in 4 copie'; '- Filtro mediano solo per l''immagine a 0° gradi'; '- Filtraggio con operazioni di convoluzione'; '- Operazione di bottom-hat'; '- Riduzione del rumore'; '- Normalizzazione'; '- Binarizzazione'; '- Operazioni morfologiche'; ''; ''};
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Units = 'pixels';
            app.Tab2.Title = 'Restaino';
            app.TextArea3 = uitextarea(app.Tab2);
            app.TextArea3.Editable = 'off';
            app.TextArea3.Value = {'La procedura di estrazione delle linee principali si basa sull’utilizzo del filtro di Gabor, che è '; 'uno dei filtri classici in letteratura.'; ''; '- Lettura dell''immagine'; '- Applicazione del filtro di Gabor'; '- Ridimensionamento dell''immagine'; '- Normalizzazione'; '- Trasformazione dell''immagine in negativo'; '- Operazione di top-hat'; '- Aumento del contrasto'; '- Binarizzazione'; '- Operazioni morfologiche'};
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Units = 'pixels';
            app.Tab3.Title = 'Dionisio';
            app.TextArea4 = uitextarea(app.Tab3);
            app.TextArea4.Editable = 'off';
            app.TextArea4.Value = {'La strategia è texture-based e si basa sulla trasformata di wavelet.'; ''; '- Lettura dell''immagine'; '- Applicazione del filtro wavelet'; '- Normalizzazione'; '- Operazione di bottom-hat'; '- Aumento del contrasto'; '- Binarizzazione'; '- Operazioni morfologiche'};
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Units = 'pixels';
            app.Tab4.Title = 'Gugliotta';
            app.TextArea5 = uitextarea(app.Tab4);
            app.TextArea5.Editable = 'off';
            app.TextArea5.Value = {'La strategia utilizza il filtro di Frost per l''estrazione delle linee principali.'; ''; '- Lettura dell''immagine'; '- Applicazione del filtro di Frost'; '- Normalizzazione'; '- Operazione di bottom-hat'; '- Aumento del contrasto'; '- Binarizzazione '; '- Operazioni morfologiche'; ''};
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Units = 'pixels';
            app.Tab5.Title = 'Marino';
            app.TextArea6 = uitextarea(app.Tab5);
            app.TextArea6.Editable = 'off';
            app.TextArea6.Value = {'Questa strategia è basata sull’analisi della texture dell’immagine, dove l’operazione di '; 'estrazione viene implementata tramite l’elaborazione dell’immagine attraverso il risultato della differenza tra due filtri Gaussiani.'; ''; '- Lettura dell''immagine'; '- Ridimensionamento dell''immagine'; '- Applicazione del filtro SRAD'; '- Differenza di filtri Gaussiani'; '- Aumento del contrasto'; '- Binarizzazione'; ''};
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Units = 'pixels';
            app.Tab6.Title = 'Micucci';
            app.TextArea7 = uitextarea(app.Tab6);
            app.TextArea7.Editable = 'off';
            app.TextArea7.Value = {'- Lettura dell''immagine'; '- Applicazione del filtro SRAD'; '- Operazione di bottom-hat'; '- Aumento del contrasto'; '- Operazioni morfologiche'};
            app.Tab7 = uitab(app.TabGroup);
            app.Tab7.Units = 'pixels';
            app.Tab7.Title = 'Calia';
            app.TextArea9 = uitextarea(app.Tab7);
            app.TextArea9.Editable = 'off';
            app.TextArea9.Value = {'Si è stato implementato un filtro ad ordine statistico.'; 'Questo permette l’elaborazione di un’immagine in modo che i pixel che la costituiscono siano '; 'classificati in base alla loro luminosità (brightness).'; 'Questi filtri sono eccellenti nel contrastare il rumore impulsivo. '; 'Rispetto ad un filtro di Smoothing ha il vantaggio di non ridurre il contrasto e non creare '; 'effetti di bordo.'};
            app.ButtonGroup2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup2.SelectionChangedFcn = createCallbackFcn(app, @RadioSelectionChanged, true);
            app.ButtonGroup2.Title = 'Strategie di analisi';
            app.ButtonGroup2.Units = 'pixels';
            app.RadioButton3 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton3.Text = 'Strategia DiBello et al.';
            app.RadioButton3.Value = true;
            app.RadioButton4 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton4.Text = 'Strategia Restaino et al.';
            app.RadioButton5 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton5.Text = 'Strategia Dionisio';
            app.RadioButton6 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton6.Text = 'Strategia Gugliotta';
            app.RadioButton7 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton7.Text = 'Strategia Marino';
            app.RadioButton8 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton8.Text = 'Strategia Micucci';
            app.RadioButton9 = uiradiobutton(app.ButtonGroup2);
            app.RadioButton9.Text = 'Strategia Calia';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @BottoneCalcolaStatistiche);
            app.Button2.Text = 'Calcola statistiche';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @BottoneVisualizzaStatistiche);
            app.Button3.Text = 'Visualizza statistiche';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = {'Avvia analisi - permette di creare i template con le linee principali della mano secondo la strategia selezionata'; 'Calcola statistiche - permette di calcolare delle statistiche da un file di score .mat già esistente'; 'Visualizza statistiche - permette di visualizzare le statistiche da files già esistenti'; 'Disegna DET - permette di disegnare, su un solo grafico, le curve DET dei risultati di varie analisi'};
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @ButtonDisegnaDETPushed);
            app.Button4.Text = 'Disegna DET';
        end
    end

    methods (Access = public)

        % Construct app
        function app = palmprintGel()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end